classdef vicon < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Button_load_data        matlab.ui.control.Button
        ViconcsvfileLabel       matlab.ui.control.Label
        EditField_filename      matlab.ui.control.EditField
        TabGroup                matlab.ui.container.TabGroup
        LeftToeTab              matlab.ui.container.Tab
        CheckBox_pos_x_ltoe     matlab.ui.control.CheckBox
        CheckBox_pos_y_ltoe     matlab.ui.control.CheckBox
        CheckBox_pos_z_ltoe     matlab.ui.control.CheckBox
        PosLabel_2              matlab.ui.control.Label
        VelLabel_2              matlab.ui.control.Label
        AccLabel_2              matlab.ui.control.Label
        CheckBox_vel_x_ltoe     matlab.ui.control.CheckBox
        CheckBox_vel_y_ltoe     matlab.ui.control.CheckBox
        CheckBox_vel_z_ltoe     matlab.ui.control.CheckBox
        CheckBox_acc_x_ltoe     matlab.ui.control.CheckBox
        CheckBox_acc_y_ltoe     matlab.ui.control.CheckBox
        CheckBox_acc_z_ltoe     matlab.ui.control.CheckBox
        LeftHeelTab             matlab.ui.container.Tab
        CheckBox_pos_x_lhee     matlab.ui.control.CheckBox
        CheckBox_pos_y_lhee     matlab.ui.control.CheckBox
        CheckBox_pos_z_lhee     matlab.ui.control.CheckBox
        PosLabel_3              matlab.ui.control.Label
        VelLabel_3              matlab.ui.control.Label
        AccLabel_3              matlab.ui.control.Label
        CheckBox_vel_x_lhee     matlab.ui.control.CheckBox
        CheckBox_vel_y_lhee     matlab.ui.control.CheckBox
        CheckBox_vel_z_lhee     matlab.ui.control.CheckBox
        CheckBox_acc_x_lhee     matlab.ui.control.CheckBox
        CheckBox_acc_y_lhee     matlab.ui.control.CheckBox
        CheckBox_acc_z_lhee     matlab.ui.control.CheckBox
        RightToeTab             matlab.ui.container.Tab
        RightHeelTab            matlab.ui.container.Tab
        DropDown_x_color        matlab.ui.control.DropDown
        DropDown_y_color        matlab.ui.control.DropDown
        DropDown_z_color        matlab.ui.control.DropDown
        DropDown_pos_linestyle  matlab.ui.control.DropDown
        DropDown_vel_linestyle  matlab.ui.control.DropDown
        DropDown_acc_linestyle  matlab.ui.control.DropDown
        h_GUIButton             matlab.ui.control.Button
    end


    properties (Access = public)
        h_foot_data_fig % handle of the foot data figure
        vicon_data        % data read in from vicon csv file. See read_vicon_csv.m for the data format
    end


    methods (Access = private)

        % Code that executes after component creation
        function startup_fcn(app)
            app.CheckBox_pos_y_ltoe.Tag = 'pos_y_ltoe';
            app.CheckBox_pos_x_ltoe.Tag = 'pos_x_ltoe';
        end

        % Callback function: Button_load_data, EditField_filename
        function pb_load_data(app, event)
            load_data(app)
        end

        % Value changed function: CheckBox_pos_x_ltoe, 
        % CheckBox_pos_y_ltoe
        function checkbox_callback(app, event)
            value = event.Source.Value;
            tag = event.Source.Tag;
            update_foot_marker_figure(app, tag, value)
        end

        % Value changed function: CheckBox_pos_x_ltoe, 
        % CheckBox_pos_y_ltoe
        function checkbox_pos_y_ltoe(app, event)
            value = app.CheckBox_pos_y_ltoe.Value;
            tag = app.CheckBox_pos_y_ltoe.Tag;
            update_foot_marker_figure(app, 'LTOE', 'y', 'pos', value)
        end

        % Value changed function: CheckBox_pos_z_ltoe
        function checkbox_pos_z_ltoe(app, event)
            value = app.CheckBox_pos_z_ltoe.Value;
            update_foot_marker_figure(app, 'LTOE', 'z', 'pos', value)
        end

        % Value changed function: CheckBox_vel_x_ltoe
        function checkbox_vel_x_ltoe(app, event)
            value = app.CheckBox_vel_x_ltoe.Value;
            update_foot_marker_figure(app, 'LTOE', 'x', 'vel', value)
        end

        % Value changed function: CheckBox_vel_y_ltoe
        function checkbox_vel_y_ltoe(app, event)
            value = app.CheckBox_vel_y_ltoe.Value;
            update_foot_marker_figure(app, 'LTOE', 'y', 'vel', value)
        end

        % Value changed function: CheckBox_vel_z_ltoe
        function checkbox_vel_z_ltoe(app, event)
            value = app.CheckBox_vel_z_ltoe.Value;
            update_foot_marker_figure(app, 'LTOE', 'z', 'vel', value)
        end

        % Value changed function: CheckBox_acc_x_ltoe
        function checkbox_acc_x_ltoe(app, event)
            value = app.CheckBox_acc_x_ltoe.Value;
            update_foot_marker_figure(app, 'LTOE', 'x', 'acc', value)
        end

        % Value changed function: CheckBox_acc_y_ltoe
        function checkbox_acc_y_ltoe(app, event)
            value = app.CheckBox_acc_y_ltoe.Value;
            update_foot_marker_figure(app, 'LTOE', 'y', 'acc', value)
        end

        % Value changed function: CheckBox_acc_z_ltoe
        function checkbox_acc_z_ltoe(app, event)
            value = app.CheckBox_acc_z_ltoe.Value;
            update_foot_marker_figure(app, 'LTOE', 'z', 'acc', value)
        end

        % Value changed function: CheckBox_pos_x_lhee
        function checkbox_pos_x_lhee(app, event)
            value = app.CheckBox_pos_x_lhee.Value;
            update_foot_marker_figure(app, 'LHEE', 'x', 'pos', value)
        end

        % Value changed function: CheckBox_pos_y_lhee
        function checkbox_pos_y_lhee(app, event)
            value = app.CheckBox_pos_y_lhee.Value;
            update_foot_marker_figure(app, 'LHEE', 'y', 'pos', value)
        end

        % Value changed function: CheckBox_pos_z_lhee
        function checkbox_pos_z_lhee(app, event)
            value = app.CheckBox_pos_z_lhee.Value;
            update_foot_marker_figure(app, 'LHEE', 'z', 'pos', value)
        end

        % Value changed function: CheckBox_vel_x_lhee
        function checkbox_vel_x_lhee(app, event)
            value = app.CheckBox_vel_x_lhee.Value;
            update_foot_marker_figure(app, 'LHEE', 'x', 'vel', value)
        end

        % Value changed function: CheckBox_vel_y_lhee
        function checkbox_vel_y_lhee(app, event)
            value = app.CheckBox_vel_y_lhee.Value;
            update_foot_marker_figure(app, 'LHEE', 'y', 'vel', value)
        end

        % Button pushed function: h_GUIButton
        function h_GUI_export(app, event)
            assignin('base','h_gui',app)
            disp('GUI handles exported to base as h_gui')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Button_load_data = uibutton(app.UIFigure, 'push');
            app.Button_load_data.ButtonPushedFcn = createCallbackFcn(app, @pb_load_data, true);
            app.Button_load_data.Text = 'Load Data';
            app.ViconcsvfileLabel = uilabel(app.UIFigure);
            app.ViconcsvfileLabel.Text = 'Vicon csv file:';
            app.EditField_filename = uieditfield(app.UIFigure, 'text');
            app.EditField_filename.ValueChangedFcn = createCallbackFcn(app, @pb_load_data, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LeftToeTab = uitab(app.TabGroup);
            app.LeftToeTab.Title = 'Left Toe';
            app.LeftToeTab.Units = 'pixels';
            app.CheckBox_pos_x_ltoe = uicheckbox(app.LeftToeTab);
            app.CheckBox_pos_x_ltoe.ValueChangedFcn = createCallbackFcn(app, @checkbox_callback, true);
            app.CheckBox_pos_x_ltoe.Text = 'X';
            app.CheckBox_pos_y_ltoe = uicheckbox(app.LeftToeTab);
            app.CheckBox_pos_y_ltoe.ValueChangedFcn = createCallbackFcn(app, @checkbox_callback, true);
            app.CheckBox_pos_y_ltoe.Text = 'Y';
            app.CheckBox_pos_z_ltoe = uicheckbox(app.LeftToeTab);
            app.CheckBox_pos_z_ltoe.ValueChangedFcn = createCallbackFcn(app, @checkbox_pos_z_ltoe, true);
            app.CheckBox_pos_z_ltoe.Text = 'Z';
            app.CheckBox_pos_z_ltoe.Value = true;
            app.PosLabel_2 = uilabel(app.LeftToeTab);
            app.PosLabel_2.Text = 'Pos';
            app.VelLabel_2 = uilabel(app.LeftToeTab);
            app.VelLabel_2.Text = 'Vel';
            app.AccLabel_2 = uilabel(app.LeftToeTab);
            app.AccLabel_2.Text = 'Acc';
            app.CheckBox_vel_x_ltoe = uicheckbox(app.LeftToeTab);
            app.CheckBox_vel_x_ltoe.ValueChangedFcn = createCallbackFcn(app, @checkbox_vel_x_ltoe, true);
            app.CheckBox_vel_x_ltoe.Text = 'X';
            app.CheckBox_vel_y_ltoe = uicheckbox(app.LeftToeTab);
            app.CheckBox_vel_y_ltoe.ValueChangedFcn = createCallbackFcn(app, @checkbox_vel_y_ltoe, true);
            app.CheckBox_vel_y_ltoe.Text = 'Y';
            app.CheckBox_vel_z_ltoe = uicheckbox(app.LeftToeTab);
            app.CheckBox_vel_z_ltoe.ValueChangedFcn = createCallbackFcn(app, @checkbox_vel_z_ltoe, true);
            app.CheckBox_vel_z_ltoe.Text = 'Z';
            app.CheckBox_acc_x_ltoe = uicheckbox(app.LeftToeTab);
            app.CheckBox_acc_x_ltoe.ValueChangedFcn = createCallbackFcn(app, @checkbox_acc_x_ltoe, true);
            app.CheckBox_acc_x_ltoe.Text = 'X';
            app.CheckBox_acc_y_ltoe = uicheckbox(app.LeftToeTab);
            app.CheckBox_acc_y_ltoe.ValueChangedFcn = createCallbackFcn(app, @checkbox_acc_y_ltoe, true);
            app.CheckBox_acc_y_ltoe.Text = 'Y';
            app.CheckBox_acc_z_ltoe = uicheckbox(app.LeftToeTab);
            app.CheckBox_acc_z_ltoe.ValueChangedFcn = createCallbackFcn(app, @checkbox_acc_z_ltoe, true);
            app.CheckBox_acc_z_ltoe.Text = 'Z';
            app.LeftHeelTab = uitab(app.TabGroup);
            app.LeftHeelTab.Title = 'Left Heel';
            app.LeftHeelTab.Units = 'pixels';
            app.CheckBox_pos_x_lhee = uicheckbox(app.LeftHeelTab);
            app.CheckBox_pos_x_lhee.ValueChangedFcn = createCallbackFcn(app, @checkbox_pos_x_lhee, true);
            app.CheckBox_pos_x_lhee.Text = 'X';
            app.CheckBox_pos_y_lhee = uicheckbox(app.LeftHeelTab);
            app.CheckBox_pos_y_lhee.ValueChangedFcn = createCallbackFcn(app, @checkbox_pos_y_lhee, true);
            app.CheckBox_pos_y_lhee.Text = 'Y';
            app.CheckBox_pos_z_lhee = uicheckbox(app.LeftHeelTab);
            app.CheckBox_pos_z_lhee.ValueChangedFcn = createCallbackFcn(app, @checkbox_pos_z_lhee, true);
            app.CheckBox_pos_z_lhee.Text = 'Z';
            app.CheckBox_pos_z_lhee.Value = true;
            app.PosLabel_3 = uilabel(app.LeftHeelTab);
            app.PosLabel_3.Text = 'Pos';
            app.VelLabel_3 = uilabel(app.LeftHeelTab);
            app.VelLabel_3.Text = 'Vel';
            app.AccLabel_3 = uilabel(app.LeftHeelTab);
            app.AccLabel_3.Text = 'Acc';
            app.CheckBox_vel_x_lhee = uicheckbox(app.LeftHeelTab);
            app.CheckBox_vel_x_lhee.ValueChangedFcn = createCallbackFcn(app, @checkbox_vel_x_lhee, true);
            app.CheckBox_vel_x_lhee.Text = 'X';
            app.CheckBox_vel_y_lhee = uicheckbox(app.LeftHeelTab);
            app.CheckBox_vel_y_lhee.ValueChangedFcn = createCallbackFcn(app, @checkbox_vel_y_lhee, true);
            app.CheckBox_vel_y_lhee.Text = 'Y';
            app.CheckBox_vel_z_lhee = uicheckbox(app.LeftHeelTab);
            app.CheckBox_vel_z_lhee.Text = 'Z';
            app.CheckBox_acc_x_lhee = uicheckbox(app.LeftHeelTab);
            app.CheckBox_acc_x_lhee.Text = 'X';
            app.CheckBox_acc_y_lhee = uicheckbox(app.LeftHeelTab);
            app.CheckBox_acc_y_lhee.Text = 'Y';
            app.CheckBox_acc_z_lhee = uicheckbox(app.LeftHeelTab);
            app.CheckBox_acc_z_lhee.Text = 'Z';
            app.RightToeTab = uitab(app.TabGroup);
            app.RightToeTab.Title = 'Right Toe';
            app.RightToeTab.Units = 'pixels';
            app.RightHeelTab = uitab(app.TabGroup);
            app.RightHeelTab.Title = 'Right Heel';
            app.RightHeelTab.Units = 'pixels';
            app.DropDown_x_color = uidropdown(app.UIFigure);
            app.DropDown_x_color.Items = {'Red', 'Green', 'Blue'};
            app.DropDown_x_color.Value = 'Red';
            app.DropDown_y_color = uidropdown(app.UIFigure);
            app.DropDown_y_color.Items = {'Red', 'Green', 'Blue'};
            app.DropDown_y_color.Value = 'Green';
            app.DropDown_z_color = uidropdown(app.UIFigure);
            app.DropDown_z_color.Items = {'Red', 'Green', 'Blue'};
            app.DropDown_z_color.Value = 'Blue';
            app.DropDown_pos_linestyle = uidropdown(app.UIFigure);
            app.DropDown_pos_linestyle.Items = {'-', '--', '-.', ':'};
            app.DropDown_pos_linestyle.Value = '-';
            app.DropDown_vel_linestyle = uidropdown(app.UIFigure);
            app.DropDown_vel_linestyle.Items = {'-', '--', '-.', ':'};
            app.DropDown_vel_linestyle.Value = '--';
            app.DropDown_acc_linestyle = uidropdown(app.UIFigure);
            app.DropDown_acc_linestyle.Items = {'-', '--', '-.', ':'};
            app.DropDown_acc_linestyle.Value = '-.';
            app.h_GUIButton = uibutton(app.UIFigure, 'push');
            app.h_GUIButton.ButtonPushedFcn = createCallbackFcn(app, @h_GUI_export, true);
            app.h_GUIButton.Text = 'h_GUI';
        end
    end

    methods (Access = public)

        % Construct app
        function app = vicon()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup_fcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end